classdef ImageHistogramsAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_2                        matlab.ui.container.Panel
        CitraAsliPanel                 matlab.ui.container.Panel
        InputPanel                     matlab.ui.container.Panel
        NamafileEditFieldLabel         matlab.ui.control.Label
        NamafileEditField              matlab.ui.control.EditField
        UkurancitraEditFieldLabel      matlab.ui.control.Label
        UkurancitraEditField           matlab.ui.control.EditField
        UkuranfilecitraEditFieldLabel  matlab.ui.control.Label
        UkuranfilecitraEditField       matlab.ui.control.EditField
        LokasicitraEditFieldLabel      matlab.ui.control.Label
        LokasicitraEditField           matlab.ui.control.EditField
        PilihfilecitrajpgLabel         matlab.ui.control.Label
        BrowserButton                  matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        DerajatkemiringanEditFieldLabel  matlab.ui.control.Label
        DerajatkemiringanEditField     matlab.ui.control.EditField
        citraLabel                     matlab.ui.control.Label
        WaktuprosesEditFieldLabel      matlab.ui.control.Label
        WaktuprosesEditField           matlab.ui.control.EditField
        deteksikemiringanLabel         matlab.ui.control.Label
        TitikpusatEditFieldLabel       matlab.ui.control.Label
        TitikpusatEditField            matlab.ui.control.EditField
        gravitasicentroidLabel         matlab.ui.control.Label
        PSNREditFieldLabel             matlab.ui.control.Label
        PSNREditField                  matlab.ui.control.EditField
        DeteksikemiringanButton        matlab.ui.control.Button
        SimpanButton                   matlab.ui.control.Button
        CitraHasilPanel                matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.Panel_2 = uipanel(app.UIFigure);
            app.CitraAsliPanel = uipanel(app.Panel_2);
            app.CitraAsliPanel.Title = 'Citra Asli';
            app.InputPanel = uipanel(app.Panel_2);
            app.InputPanel.Title = 'Input';
            app.NamafileEditFieldLabel = uilabel(app.InputPanel);
            app.NamafileEditFieldLabel.Text = 'Nama file';
            app.NamafileEditField = uieditfield(app.InputPanel, 'text');
            app.UkurancitraEditFieldLabel = uilabel(app.InputPanel);
            app.UkurancitraEditFieldLabel.Text = 'Ukuran citra';
            app.UkurancitraEditField = uieditfield(app.InputPanel, 'text');
            app.UkuranfilecitraEditFieldLabel = uilabel(app.InputPanel);
            app.UkuranfilecitraEditFieldLabel.Text = 'Ukuran file citra';
            app.UkuranfilecitraEditField = uieditfield(app.InputPanel, 'text');
            app.LokasicitraEditFieldLabel = uilabel(app.InputPanel);
            app.LokasicitraEditFieldLabel.Text = 'Lokasi citra';
            app.LokasicitraEditField = uieditfield(app.InputPanel, 'text');
            app.PilihfilecitrajpgLabel = uilabel(app.InputPanel);
            app.PilihfilecitrajpgLabel.Text = 'Pilih file citra (jpg) ';
            app.BrowserButton = uibutton(app.InputPanel, 'push');
            app.BrowserButton.Text = 'Browser';
            app.CitraHasilPanel = uipanel(app.Panel_2);
            app.CitraHasilPanel.Title = 'Citra Hasil';
            app.OutputPanel = uipanel(app.Panel_2);
            app.OutputPanel.Title = 'Output';
            app.DerajatkemiringanEditFieldLabel = uilabel(app.OutputPanel);
            app.DerajatkemiringanEditFieldLabel.Text = 'Derajat kemiringan';
            app.DerajatkemiringanEditField = uieditfield(app.OutputPanel, 'text');
            app.citraLabel = uilabel(app.OutputPanel);
            app.citraLabel.Text = 'citra';
            app.WaktuprosesEditFieldLabel = uilabel(app.OutputPanel);
            app.WaktuprosesEditFieldLabel.Text = 'Waktu proses';
            app.WaktuprosesEditField = uieditfield(app.OutputPanel, 'text');
            app.deteksikemiringanLabel = uilabel(app.OutputPanel);
            app.deteksikemiringanLabel.Text = 'deteksi kemiringan';
            app.TitikpusatEditFieldLabel = uilabel(app.OutputPanel);
            app.TitikpusatEditFieldLabel.Text = 'Titik pusat';
            app.TitikpusatEditField = uieditfield(app.OutputPanel, 'text');
            app.gravitasicentroidLabel = uilabel(app.OutputPanel);
            app.gravitasicentroidLabel.Text = 'gravitasi (centroid)';
            app.PSNREditFieldLabel = uilabel(app.OutputPanel);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.OutputPanel, 'text');
            app.DeteksikemiringanButton = uibutton(app.OutputPanel, 'push');
            app.DeteksikemiringanButton.Text = 'Deteksi kemiringan';
            app.SimpanButton = uibutton(app.OutputPanel, 'push');
            app.SimpanButton.Text = 'Simpan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageHistogramsAppExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end